classdef hlavniOkno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STARTButton                     matlab.ui.control.Button
        mask                            matlab.ui.control.NumericEditField
        masknetestucha1ANO0NEEditFieldLabel  matlab.ui.control.Label
        zvysovani                       matlab.ui.control.NumericEditField
        zvysovaniintenzity1schod0spojEditFieldLabel  matlab.ui.control.Label
        pasmoMaxFreq                    matlab.ui.control.NumericEditField
        doEditFieldLabel                matlab.ui.control.Label
        sumIntenzita                    matlab.ui.control.NumericEditField
        intenzita090dBEditField_2Label  matlab.ui.control.Label
        pasmoMinFreq                    matlab.ui.control.NumericEditField
        frekvennpsmo120000HzodEditFieldLabel  matlab.ui.control.Label
        intenzitaMaskTonu               matlab.ui.control.NumericEditField
        intenzita090dBEditFieldLabel    matlab.ui.control.Label
        frekvenceMaskTonu               matlab.ui.control.NumericEditField
        frekvencetnu120000HzEditFieldLabel  matlab.ui.control.Label
        novaFrekvenceZobrazeni          matlab.ui.control.NumericEditField
        Novfrekvence120000HzEditFieldLabel  matlab.ui.control.Label
        navodButton                     matlab.ui.control.Button
        MAINFUNKCELabel                 matlab.ui.control.Label
        nastaveniButton                 matlab.ui.control.Button
        dBLabel                         matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        kontrolamaskovachoumuLabel      matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        kontrolamaskovachotnuLabel      matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        kontrolatestovachotnuLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    %pořešit radio buttons

    properties (Access = private)

        nastaveni % Description
        navod


    end

    methods (Access = public)
        %hlaní funkce pro přeposílání dat mezi main a nastavením
        function importNastaveni(app,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu,frequencyNew,frekvenceMaskTonu,intenzitaMaskTonu,pasmoMinFreq,pasmoMaxFreq,sumIntenzita)
            app.mask.Value=MaskovaniNetestovanehoUcha;
            app.zvysovani.Value=ZvysovaniIntenzityTonu;
            app.novaFrekvenceZobrazeni.Value=frequencyNew;
            app.frekvenceMaskTonu.Value=frekvenceMaskTonu;
            app.intenzitaMaskTonu.Value=intenzitaMaskTonu;
            app.pasmoMinFreq.Value=pasmoMinFreq;
            app.pasmoMaxFreq.Value=pasmoMaxFreq;
            app.sumIntenzita.Value=sumIntenzita;


        end
    end

    methods (Access = private)


        function results = Measurement(app)
            app.STARTButton.Text = 'slyším tón';
            %uigetfile('funkce60.mat') 
            P_sampled = importdata('funkce60.mat');
            %load('exp_function.mat');
            P_sampled = P_sampled'; % transpozice vektoru
            P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku

            Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
            t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
            t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
            f = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
            for i = 1:1:numel(f)

                frequence = f(i);
                signal = sin(frequence*t);
                signal = signal.*P_sampled;%ramp;

                for k = 1:2
                    if k == 1
                        %přehrání sinusové vlny na různých frekvencích do levého ucha
                        sound([signal;zeros(size(t))],Fs);
                        tic
                       % waitforbuttonpress;
                        timeLeft(i) = toc;
                        clear sound;
                    end

                    if k == 2
                        %přehrání sinusové vlny na různých frekvencích do pravého ucha
                        sound([zeros(size(t)); signal],Fs);
                        tic
                        %waitforbuttonpress;
                        timeRight(i) = toc;
                        clear sound;
                    end
                end
            end

        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: nastaveniButton
        function nastaveniButtonPushed(app, event)
            app.nastaveni=nastaveni(app);
        end

        % Button pushed function: navodButton
        function navodButtonPushed(app, event)
            app.navod=navod(app);
        end

        % Value changed function: novaFrekvenceZobrazeni
        function novaFrekvenceZobrazeniValueChanged(app, event)
            value = app.novaFrekvenceZobrazeni.Value;

        end

        % Value changed function: frekvenceMaskTonu
        function frekvenceMaskTonuValueChanged(app, event)
            value = app.frekvenceMaskTonu.Value;

        end

        % Value changed function: intenzitaMaskTonu
        function intenzitaMaskTonuValueChanged(app, event)
            value = app.intenzitaMaskTonu.Value;

        end

        % Value changed function: pasmoMinFreq
        function pasmoMinFreqValueChanged(app, event)
            value = app.pasmoMinFreq.Value;

        end

        % Value changed function: sumIntenzita
        function sumIntenzitaValueChanged(app, event)
            value = app.sumIntenzita.Value;

        end

        % Value changed function: pasmoMaxFreq
        function pasmoMaxFreqValueChanged(app, event)
            value = app.pasmoMaxFreq.Value;

        end

        % Value changed function: mask
        function maskValueChanged(app, event)
            value = app.mask.Value;

        end

        % Value changed function: zvysovani
        function zvysovaniValueChanged(app, event)
            value = app.zvysovani.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % delete(app.nastaveni)
            %delete(app.navod)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

           %AL = [8.89182357831544	5.93954344481664	10.2574876337944	3.45509667391978	7.44112118393599	5.06912356105390	13.8889059673946	41.7715719952750	36.2341162287780	48.0916778223027];
           % AR = [16.0637229280463	10.9009099238993	2.96930434778657	2.08140809850342	3.15865339760848	1.65388362300003	11.1032749671450	19.4895627025782	17.0245532186875	67.6337379184429];
           % plot(app.UIAxes,f,AL,'r-x',f,AR,'b-o');
          app.Measurement();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AUDIOGRAM')
            xlabel(app.UIAxes, 'Frekvence Hz')
            ylabel(app.UIAxes, 'SPL dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [0 90];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.kontrolatestovachotnuLabel = uilabel(app.UIFigure);
            app.kontrolatestovachotnuLabel.Text = 'kontrola testovacího tónu:';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.kontrolamaskovachotnuLabel = uilabel(app.UIFigure);
            app.kontrolamaskovachotnuLabel.Text = 'kontrola maskovacího tónu:';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.kontrolamaskovachoumuLabel = uilabel(app.UIFigure);
            app.kontrolamaskovachoumuLabel.Text = 'kontrola maskovacího šumu:';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.nastaveniButton = uibutton(app.UIFigure, 'push');
            app.nastaveniButton.ButtonPushedFcn = createCallbackFcn(app, @nastaveniButtonPushed, true);
            app.nastaveniButton.Text = 'nastaveni';
            app.MAINFUNKCELabel = uilabel(app.UIFigure);
            app.MAINFUNKCELabel.Text = 'MAIN FUNKCE';
            app.navodButton = uibutton(app.UIFigure, 'push');
            app.navodButton.ButtonPushedFcn = createCallbackFcn(app, @navodButtonPushed, true);
            app.navodButton.Text = 'navod';
            app.Novfrekvence120000HzEditFieldLabel = uilabel(app.UIFigure);
            app.Novfrekvence120000HzEditFieldLabel.Text = 'Nová frekvence  (1-20 000 Hz)';
            app.novaFrekvenceZobrazeni = uieditfield(app.UIFigure, 'numeric');
            app.novaFrekvenceZobrazeni.ValueChangedFcn = createCallbackFcn(app, @novaFrekvenceZobrazeniValueChanged, true);
            app.frekvencetnu120000HzEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencetnu120000HzEditFieldLabel.Text = ' frekvence tónu (1-20 000 Hz)';
            app.frekvenceMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.frekvenceMaskTonu.ValueChangedFcn = createCallbackFcn(app, @frekvenceMaskTonuValueChanged, true);
            app.intenzita090dBEditFieldLabel = uilabel(app.UIFigure);
            app.intenzita090dBEditFieldLabel.Text = 'intenzita  (0-90 dB)';
            app.intenzitaMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.intenzitaMaskTonu.ValueChangedFcn = createCallbackFcn(app, @intenzitaMaskTonuValueChanged, true);
            app.frekvennpsmo120000HzodEditFieldLabel = uilabel(app.UIFigure);
            app.frekvennpsmo120000HzodEditFieldLabel.Text = 'frekvenční pásmo (1-20000 Hz) od:';
            app.pasmoMinFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMinFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMinFreqValueChanged, true);
            app.intenzita090dBEditField_2Label = uilabel(app.UIFigure);
            app.intenzita090dBEditField_2Label.Text = 'intenzita  (0-90 dB)';
            app.sumIntenzita = uieditfield(app.UIFigure, 'numeric');
            app.sumIntenzita.ValueChangedFcn = createCallbackFcn(app, @sumIntenzitaValueChanged, true);
            app.doEditFieldLabel = uilabel(app.UIFigure);
            app.doEditFieldLabel.Text = 'do:';
            app.pasmoMaxFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMaxFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMaxFreqValueChanged, true);
            app.zvysovaniintenzity1schod0spojEditFieldLabel = uilabel(app.UIFigure);
            app.zvysovaniintenzity1schod0spojEditFieldLabel.Text = 'zvysovani intenzity(1=schod, 0 = spoj)';
            app.zvysovani = uieditfield(app.UIFigure, 'numeric');
            app.zvysovani.ValueChangedFcn = createCallbackFcn(app, @zvysovaniValueChanged, true);
            app.masknetestucha1ANO0NEEditFieldLabel = uilabel(app.UIFigure);
            app.masknetestucha1ANO0NEEditFieldLabel.Text = 'mask netest ucha (1= ANO, 0 = NE)';
            app.mask = uieditfield(app.UIFigure, 'numeric');
            app.mask.ValueChangedFcn = createCallbackFcn(app, @maskValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hlavniOkno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end